classdef temperatura_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        EscalonButton                   matlab.ui.control.Button
        TemperaturaGauge                matlab.ui.control.Gauge
        TemperaturaGaugeLabel           matlab.ui.control.Label
        EstadodelcalefactorLamp         matlab.ui.control.Lamp
        EstadodelcalefactorLampLabel    matlab.ui.control.Label
        EnviarButton                    matlab.ui.control.Button
        IntervalodemuestreomsEditField  matlab.ui.control.NumericEditField
        IntervalodemuestreomsEditFieldLabel  matlab.ui.control.Label
        HisteresisCEditField            matlab.ui.control.NumericEditField
        HisteresisCEditFieldLabel       matlab.ui.control.Label
        SetpointCEditField              matlab.ui.control.NumericEditField
        SetpointCEditFieldLabel         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        BaudRateDropDown                matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        PuertoDropDown                  matlab.ui.control.DropDown
        PuertoDropDownLabel             matlab.ui.control.Label
        ConectarButton                  matlab.ui.control.Button
        ConexcionconelCLamp             matlab.ui.control.Lamp
        ConexcionconelCLampLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
            serialObj % Objeto para manejar la conexión serie
            tempData % Objeto para manejar la temperatura
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)

        function readSerialData(app,src,~) % Make Sure to add 'app' as first arguement and 'src' as second arguement
            % Leer la línea de datos crudos del puerto serie
            raw = readline(src);

            % Mostrar los datos crudos recibidos
            % disp(raw);

            % Separar los datos recibidos, asumiendo que están en formato CSV
            data = str2double(strsplit(raw, ','));

            % Verificar si se obtuvieron los datos correctamente
            if length(data) >= 2
                % Primer dato: Estado del calefactor (0 o 1)
                estadoCalefactor = data(2);

                % Actualizar el color de la lámpara según el estado del calefactor
                if estadoCalefactor == 1
                    app.EstadodelcalefactorLamp.Color = 'green'; % Calefactor encendido
                else
                    app.EstadodelcalefactorLamp.Color = 'red';   % Calefactor apagado
                end

                % Segundo dato: Temperatura (valor numérico a dos decimales)
                temperatura = data(1);
                disp(temperatura);
                % Verificar si existe la variable app.tempData.animatedLine
                if ~isfield(app.tempData, 'time') || ~isfield(app.tempData, 'temp') || isempty(app.tempData.time) || isempty(app.tempData.temp)
                    % Crea los campos si no existen
                    app.tempData.temp = [];
                    app.tempData.time = [];
                    app.tempData.spHistorico = [];
                end

                % Actualizar el valor del gauge con la temperatura actual
                app.TemperaturaGauge.Value = temperatura;

                % Añadir el nuevo punto de temperatura al gráfico
                t = datetime('now'); % Usar la fecha y hora actual para el eje x
                app.tempData.temp = [app.tempData.temp,temperatura];
                app.tempData.time = [app.tempData.time,t];
                app.tempData.spHistorico = [app.tempData.spHistorico,app.tempData.setpoint];

                hold(app.UIAxes, 'on');
                % Graficar la temperatura y capturar el identificador de la línea
                hTemp = plot(app.UIAxes, app.tempData.time, app.tempData.temp, 'Color', 'r');

                % Graficar el setpoint y capturar el identificador de la línea
                hSetpoint = plot(app.UIAxes, app.tempData.time, app.tempData.spHistorico, 'Color', 'b', 'LineStyle', '--');

                % Ajustar los límites del eje x para mostrar los últimos 30 segundos
                app.UIAxes.XLim = [t - minutes(30/60), t]; % Mostrar los últimos 30 segundos
                app.UIAxes.YLim = [0, 85]; 

                % Configurar la leyenda utilizando los identificadores de las líneas
                legend(app.UIAxes, [hTemp, hSetpoint], {'DS1820', 'setpoint'});
                % legend(app.UIAxes,{'DS1820'});
                % Redibujar el gráfico
                drawnow(app.UIAxes);
            else
                % Do nothing
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% UPADTE COM PORT DROPDOWN
            % Obtener la lista de puertos COM disponibles
            availablePorts = serialportlist("available");

            % Actualizar el DropDown con la lista de puertos COM
            app.PuertoDropDown.Items = availablePorts;

            % Establecer el primer puerto como valor seleccionado por defecto
            if ~isempty(availablePorts)
                app.PuertoDropDown.Value = availablePorts{1};
            else
                app.PuertoDropDown.Value = ''; % Dejar vacío si no hay puertos disponibles
            end
            %% UPADTE BAUD RATES DROP DOWN
            % Definir los valores típicos de baud rate
            baudRates = {'9600', '14400', '19200', '38400', '57600', '115200'};

            % Actualizar los elementos del DropDown de BaudRate
            app.BaudRateDropDown.Items = baudRates;

            % Establecer 9600 como valor por defecto
            app.BaudRateDropDown.Value = '9600';
            
            %% SAVE DEFAULTA VALUES
            % Leer los valores de los campos de texto
            app.tempData.setpoint = app.SetpointCEditField.Value;
            app.tempData.histeresis = app.HisteresisCEditField.Value;
            app.tempData.intervalo = app.IntervalodemuestreomsEditField.Value;
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            % Leer los valores de los campos de texto
            app.tempData.setpoint = app.SetpointCEditField.Value;
            app.tempData.histeresis = app.HisteresisCEditField.Value;
            app.tempData.intervalo = app.IntervalodemuestreomsEditField.Value;

            % Formatear la información como CSV y enviarla por el puerto serie
            mensaje = sprintf('%d,%d,%d', round(app.tempData.setpoint), round(app.tempData.histeresis), round(app.tempData.intervalo));
            mensaje = [char(0x02),mensaje,char(0x03)];
            fprintf(app.serialObj, mensaje);
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            % Leer las selecciones de los menús desplegables
            selectedPort = app.PuertoDropDown.Value;
            selectedBaudRate = str2double(app.BaudRateDropDown.Value);

            % Crear y configurar la conexión serie
            try
                app.serialObj = serialport(selectedPort, selectedBaudRate);
                configureTerminator(app.serialObj, "LF"); % Configurar terminador (opcional)
                app.serialObj.Timeout = 10; % Establecer el tiempo de espera para operaciones
                configureCallback(app.serialObj,"terminator",@(src, event) readSerialData(app,src,event));

                % Cambiar el color del indicador a verde para mostrar que la conexión es exitosa
                app.ConexcionconelCLamp.Color = 'green';

                % Habilitar el botón Enviar
                app.EnviarButton.Enable = 'on';
                app.EscalonButton.Enable = 'on';

                EscalonButtonPushed(app,event);
            catch ME
                % Cambiar el color del indicador a rojo si ocurre un problema con la conexión serie
                app.ConexcionconelCLamp.Color = 'red';

                % Deshabilitar el botón Enviar
                app.EnviarButton.Enable = 'off';
                app.EscalonButton.Enable = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Verificar si el objeto serialObj existe y no está vacío
            if ~isempty(app.serialObj)
                % Cerrar el puerto serie
                clear app.serialObj;

                % Opcional: Actualizar la interfaz para indicar que el puerto se cerró
                app.ConexcionconelCLamp.Color = 'red';
                app.EnviarButton.Enable = 'off';
            end

            delete(app)
        end

        % Button pushed function: EscalonButton
        function EscalonButtonPushed(app, event)
            % Incrementar el valor de setpoint en 30
            app.tempData.setpoint = app.tempData.setpoint + 30;

            % Actualizar algún componente de la interfaz si es necesario
            % Por ejemplo, actualizar un indicador del valor del setpoint
            app.SetpointCEditField.Value = app.tempData.setpoint;
    
            % Enviar setpoint al microC 
            EnviarButtonPushed(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {217, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SetpointCEditFieldLabel = uilabel(app.LeftPanel);
            app.SetpointCEditFieldLabel.Text = 'Setpoint [°C]';
            app.SetpointCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetpointCEditField.Value = 30;
            app.HisteresisCEditFieldLabel = uilabel(app.LeftPanel);
            app.HisteresisCEditFieldLabel.Text = 'Histeresis [°C]';
            app.HisteresisCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HisteresisCEditField.Value = 2;
            app.IntervalodemuestreomsEditFieldLabel = uilabel(app.LeftPanel);
            app.IntervalodemuestreomsEditFieldLabel.Text = {'Intervalo de '; 'muestreo [ms]'};
            app.IntervalodemuestreomsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalodemuestreomsEditField.Value = 1000;
            app.EnviarButton = uibutton(app.LeftPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Enable = 'off';
            app.EnviarButton.Text = 'Enviar';
            app.EstadodelcalefactorLampLabel = uilabel(app.LeftPanel);
            app.EstadodelcalefactorLampLabel.Text = 'Estado del calefactor';
            app.EstadodelcalefactorLamp = uilamp(app.LeftPanel);
            app.TemperaturaGaugeLabel = uilabel(app.LeftPanel);
            app.TemperaturaGaugeLabel.Text = 'Temperatura';
            app.TemperaturaGauge = uigauge(app.LeftPanel, 'circular');
            app.EscalonButton = uibutton(app.LeftPanel, 'push');
            app.EscalonButton.ButtonPushedFcn = createCallbackFcn(app, @EscalonButtonPushed, true);
            app.EscalonButton.Enable = 'off';
            app.EscalonButton.Text = 'Escalon';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Temperatura vs. Tiempo')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Temperatura [°C]')
            zlabel(app.UIAxes, 'Z')
            app.ConexcionconelCLampLabel = uilabel(app.RightPanel);
            app.ConexcionconelCLampLabel.Text = 'Conexcion con el µC';
            app.ConexcionconelCLamp = uilamp(app.RightPanel);
            app.ConectarButton = uibutton(app.RightPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.PuertoDropDownLabel = uilabel(app.RightPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.RightPanel);
            app.BaudRateDropDownLabel = uilabel(app.RightPanel);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = temperatura_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end